classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Image10            matlab.ui.control.Image
        SwitchLabel        matlab.ui.control.Label
        Image8_4           matlab.ui.control.Image
        Image8_3           matlab.ui.control.Image
        Image8_2           matlab.ui.control.Image
        Image8             matlab.ui.control.Image
        Image7             matlab.ui.control.Image
        Label              matlab.ui.control.Label
        Image6             matlab.ui.control.Image
        audioSlider        matlab.ui.control.Slider
        Image4             matlab.ui.control.Image
        Image3             matlab.ui.control.Image
        Knob               matlab.ui.control.Knob
        KnobLabel          matlab.ui.control.Label
        Switch             matlab.ui.control.Switch
        ModeDropDown       matlab.ui.control.DropDown
        ModeDropDownLabel  matlab.ui.control.Label
        TrackDropDown      matlab.ui.control.DropDown
        StopButton         matlab.ui.control.Button
        songLabel          matlab.ui.control.Label
        Slider_31          matlab.ui.control.Slider
        KSlider_14Label    matlab.ui.control.Label
        Slider_30          matlab.ui.control.Slider
        KSlider_13Label    matlab.ui.control.Label
        Slider_29          matlab.ui.control.Slider
        KSlider_12Label    matlab.ui.control.Label
        Slider_28          matlab.ui.control.Slider
        KSlider_11Label    matlab.ui.control.Label
        Slider_27          matlab.ui.control.Slider
        KSlider_10Label    matlab.ui.control.Label
        Slider_26          matlab.ui.control.Slider
        KSlider_9Label     matlab.ui.control.Label
        Slider_25          matlab.ui.control.Slider
        KSlider_8Label     matlab.ui.control.Label
        Slider_24          matlab.ui.control.Slider
        KSlider_7Label     matlab.ui.control.Label
        Slider_23          matlab.ui.control.Slider
        KSlider_6Label     matlab.ui.control.Label
        Slider_22          matlab.ui.control.Slider
        KSlider_5Label     matlab.ui.control.Label
        Slider_21          matlab.ui.control.Slider
        KSlider_4Label     matlab.ui.control.Label
        Slider_20          matlab.ui.control.Slider
        KSlider_3Label     matlab.ui.control.Label
        Slider_19          matlab.ui.control.Slider
        KSlider_2Label     matlab.ui.control.Label
        Slider_18          matlab.ui.control.Slider
        KSliderLabel       matlab.ui.control.Label
        Slider_17          matlab.ui.control.Slider
        Label_18           matlab.ui.control.Label
        Slider_16          matlab.ui.control.Slider
        Label_17           matlab.ui.control.Label
        Slider_15          matlab.ui.control.Slider
        Label_16           matlab.ui.control.Label
        Slider_14          matlab.ui.control.Slider
        Label_15           matlab.ui.control.Label
        Slider_13          matlab.ui.control.Slider
        Label_14           matlab.ui.control.Label
        Slider_12          matlab.ui.control.Slider
        Label_13           matlab.ui.control.Label
        Slider_11          matlab.ui.control.Slider
        Label_12           matlab.ui.control.Label
        Slider_10          matlab.ui.control.Slider
        Label_11           matlab.ui.control.Label
        Slider_9           matlab.ui.control.Slider
        Label_10           matlab.ui.control.Label
        Slider_8           matlab.ui.control.Slider
        Label_9            matlab.ui.control.Label
        Slider_7           matlab.ui.control.Slider
        Label_8            matlab.ui.control.Label
        Slider_6           matlab.ui.control.Slider
        Label_6            matlab.ui.control.Label
        Slider_5           matlab.ui.control.Slider
        Label_5            matlab.ui.control.Label
        Slider_4           matlab.ui.control.Slider
        Label_7            matlab.ui.control.Label
        Slider_3           matlab.ui.control.Slider
        Label_4            matlab.ui.control.Label
        Slider_2           matlab.ui.control.Slider
        Label_3            matlab.ui.control.Label
        Duration           matlab.ui.control.Label
        Slider_1           matlab.ui.control.Slider
        PlayButton         matlab.ui.control.Button
        Label_2            matlab.ui.control.Label
        Image2             matlab.ui.control.Image
        Image5             matlab.ui.control.Image
        Image5_2           matlab.ui.control.Image
        Image5_3           matlab.ui.control.Image
        Image11            matlab.ui.control.Image
        UIAxes             matlab.ui.control.UIAxes
        ContextMenu        matlab.ui.container.ContextMenu
        Menu               matlab.ui.container.Menu
        Menu2              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        total_i ;
        duration ;
        isRec = 0;
        isPlay = 0;
        fs = 44100;
        fk = [10 20 22.4 25 28.2 31.5 35.5 40 44.7 50 56.2 63 ...
            70.8 80 89.1 100 112 125 141 160 178 200 224 ...
            250 ...
            282 315 355 400 447 500 562 630 708 800 891 ...
            1000 ...
            1120 1250 1410 1600 1780 2000 2240 2500 2820 ...
            3150 3550 4000 4470 5000 5620 6300 7080 8000 ...
            8910 10000 11200 12500 14100 16000 17800 20000];
        
        wn = 2*pi*[10 14 20 21.2 22.4 23.7 25 26.6 28.2 ...
            29.9 ...
            31.5 33.5 35.5 37.6 40 42.2 44.7 47.3 50 53.1 ...
            56.2 59.6 63 66.9 70.8 75 80 84.2 89.1 94.5 ...
            100 ...
            106 112 119 125 133 141 150 160 168 178 188 200 ...
            211 224 237 250 266 282 299 315 335 355 376 ...
            400 422 447 473 500 531 562 596 630 669 708 ...
            750 800 842 891 945 1000 1060 1120 1190 1250 ...
            1330 1410 1500 1600 1680 1780 1880 2000 2110 ...
            2240 2370 2500 2660 2820 2990 3150 3350 ...
            3550 3760 4000 4220 4470 4730 5000 5310 5620 ...
            5960 ...
            6300 6690 7080 7500 8000 8420 8910 9450 10000 ...
            10600 11200 11900 12500 13300 14100 15000 16000 ...
            16800 17800 18800 20000];
        
        fg = [20 25 31.5 40 50 63 80 100 125 160 200 250 ...
            315 400 500 630 800 1000 1250 1600 2000 2500 ...
            3150 4000 5000 6300 8000 ...
            10000 12500 16000 20000];
        isforwardback = 0;
        isStop = 0;

    end
    
    
    methods (Access = private)
        
        function a = den(app,k,Fs)
            thetak = 2*pi*app.fk(k)/Fs;
            if k>1 && k<62
                dthetak = (2*pi*app.fk(k+1)/Fs-2*pi*app.fk(k-1)/Fs)/2;
            elseif k == 1
                dthetak = 2*pi*app.fk(2)/Fs-2*pi*app.fk(1)/Fs;
            else
                dthetak = 2*pi*app.fk(62)/Fs-2*pi*app.fk(61)/Fs;
            end
            
            pk = exp(-dthetak/2);
            a = [1 -2*pk*cos(thetak) pk^2];
            
        end
        %Calculation of the modelling matrix
        function Mrplus = Mrp(app,Fs)

            M = zeros(123,124);
            M(:,125) = ones(123,1);
            sqW = Weight(app,app.Slider_1.Value,...
            app.Slider_2.Value,...
            app.Slider_3.Value,app.Slider_4.Value,...
            app.Slider_5.Value,app.Slider_6.Value,...
            app.Slider_7.Value,app.Slider_8.Value,...
            app.Slider_9.Value,app.Slider_10.Value,...
            app.Slider_11.Value,app.Slider_12.Value,...
            app.Slider_13.Value,app.Slider_14.Value,...
            app.Slider_15.Value,app.Slider_16.Value,...
            app.Slider_17.Value,app.Slider_18.Value,...
            app.Slider_19.Value,app.Slider_20.Value,...
            app.Slider_21.Value,app.Slider_22.Value,...
            app.Slider_23.Value,app.Slider_24.Value,...
            app.Slider_25.Value,app.Slider_26.Value,...
            app.Slider_27.Value,app.Slider_28.Value,...
            app.Slider_29.Value,app.Slider_30.Value,...
            app.Slider_31.Value);
            
            a = [];
            for n = 1:123
                for k = 1:62
                    M(n,2*k-1) = 1/(den(app,k,Fs)*...
                    [1; exp(-app.wn(n)/Fs*1i); ...
                    exp(-2*app.wn(n)/Fs*1i)]);
                    M(n,2*k) = exp(-app.wn(n)/Fs*1i)/...
                    (den(app,k,Fs)*[1; ...
                    exp(-app.wn(n)/Fs*1i);...
                    exp(-2*app.wn(n)/Fs*1i)]);
                    if (n==1)
                        a = [a, den(app,k,Fs)];
                    end
                end
                
                
                M(n,:) = M(n,:)*sqW(n);
            end
            save 'a.mat' a ;
            
            Mr = [real(M);imag(M)];
            
            Mrplus = (transpose(Mr)*Mr)\transpose(Mr);
            
        end
        %Calculation of the target response vector
        function htr = target(app,G1,G2,G3,G4,G5,G6,G7,...
        G8,G9,G10,G11,G12,G13,G14,G15,G16,G17,G18,G19,...
        G20,G21,G22,G23,G24,G25,G26,G27,G28,G29,G30,G31)
        
        y = 10.^(1/20*pchip([-flip(app.fg) app.fg],...
        [flip([G1,G2,G3,G4,G5,G6,G7,G8,G9,G10,...
        G11,G12,G13,G14,G15,G16,G17,G18,G19,G20,...
        G21,G22,G23,G24,G25,G26,G27,...
        G28,G29,G30,G31])...
        [G1,G2,G3,G4,G5,G6,G7,G8,G9,G10,G11,G12,...
        G13,G14,G15,G16,G17,G18,G19,G20,G21,G22,...
        G23,G24,G25,G26,G27,G28,G29,G30,G31]],...
        linspace(-app.fs/2,app.fs/2,2^16)))';
        save ht.mat y;
        
        phase = unwrap(imag(-hilbert(log(y))));
        phase = phase(32769:64124);
        
        i = 1;
        fi = zeros(1,123);
        for w = app.wn/(2*pi)
            if round(w*length(phase)/21100) > length(phase)
                fi(i) = phase(length(phase));
            else
                fi(i) = phase(round(w*length(phase)/21100));
            end
            i=i+1;
        end
        
        htr = [real(exp(1i*fi'));imag(exp(1i*fi'))];
        end
        %Calculation of the optimal numerator coefficients
        function popt = num(~, htr, Mrplus)
            popt = Mrplus*htr;
        end
        %Filtering process algorithm
        function yk = filterNew(app,bWithIndex,xk1,...
            ybuffer,xbuffer,Fs,n)
            yk = filter(bWithIndex',den(app,n,Fs),...
            xk1,filtic(bWithIndex',den(app,n,Fs),...
            ybuffer,xbuffer));    
        end
        %Calculation of the Weighting factors
        function sqW = ...
                Weight(app,G1,G2,G3,G4,G5,G6,G7,G8,G9,...
                G10,G11,G12,G13,G14,G15,G16,G17,G18,G19,G20,G21,...
                G22,G23,G24,G25,G26,G27,G28,G29,G30,G31)
                ht = 10.^(1/20*pchip([10 app.fg],[G1 ...
                G1,G2,G3,G4,...
                G5,G6,G7,G8,G9,G10,G11,G12,G13,G14,G15,G16,...
                G17,G18,G19,G20,G21,G22,G23,G24,G25,G26,G27,...
                G28,G29,G30,G31],app.wn/(2*pi)))';
                sqW1 = zeros(123,1);
            for i = 1:123
                sqW1(i) = 1/ht(i);
            end
            sqW = sqW1;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tracks = struct2cell(dir('*.mp3'));
            tracks = tracks(1,:);
            app.TrackDropDown.Items = tracks;
            wav = struct2cell(dir('*.wav'));
            wav = wav(1,:);
            for i = 1:length(wav)
            app.TrackDropDown.Items(i+...
            length(tracks(1,:))) = wav(i);
            end
            if isempty(app.TrackDropDown.Items) == 1
                uialert(app.UIFigure,('Your current folder does not contain any audio files'),...
                'Info','Icon','info');
            else
                duration = seconds(audioinfo(char(app.TrackDropDown.Value)).Duration);
                duration.Format = 'mm:ss';
                app.Duration.Text = string(duration);
                app.Label.Text = '0:00';
            end
            
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            if isempty(app.TrackDropDown.Items) == 1
                uialert(app.UIFigure,'No audio file found',...
               'Error','Icon','error');
            elseif app.isRec == 1
                uialert(app.UIFigure,...
                'Stop recording before playback of a file',...
                'Tip','Icon','warning');
            
            elseif strcmp(app.PlayButton.Text,'Play') && ...
                app.isPlay == 0 && app.isRec == 0
                

                %Acquiring the audio file
                fileReader = dsp.AudioFileReader(...
                char(app.TrackDropDown.Value),...
                'SamplesPerFrame',1024);
               
                deviceWriter = audioDeviceWriter('SampleRate',...
                fileReader.SampleRate);
                %file_name
                fileName = fileReader.Filename;
                
                %% cal number of frame fileReader (total_i)
                app.audioSlider.Limits = [0 floor(audioinfo(char(app.TrackDropDown.Value)).TotalSamples...
                        /fileReader.SamplesPerFrame)];
                app.Label.Text = num2str(app.audioSlider.Limits(2));
                app.total_i = app.audioSlider.Limits(2);
                
                %app.audioSlider.Limits=[]
                duration = seconds(audioinfo(char(app.TrackDropDown.Value)).Duration);
                app.duration = duration;
                duration.Format = 'mm:ss';
                app.Duration.Text = string(duration);
                
                app.PlayButton.Text = 'Pause';
                app.isPlay = 1;
                app.fs = fileReader.SampleRate;
                Fs = app.fs;
                
                %Initializing the delays
                xbuffer1 = 0;
                xbuffer2 = 0;
                ybuffer1 = zeros(62,3);
                ybuffer2 = zeros(62,3);
                
                
                S1 = app.Slider_1.Value;
                S2 = app.Slider_2.Value;
                S3 = app.Slider_3.Value;
                S4 = app.Slider_4.Value;
                S5 = app.Slider_5.Value;
                S6 = app.Slider_6.Value;
                S7 = app.Slider_7.Value;
                S8 = app.Slider_8.Value;
                S9 = app.Slider_9.Value;
                S10 = app.Slider_10.Value;
                S11 = app.Slider_11.Value;
                S12 = app.Slider_12.Value;
                S13 = app.Slider_13.Value;
                S14 = app.Slider_14.Value;
                S15 = app.Slider_15.Value;
                S16 = app.Slider_16.Value;
                S17 = app.Slider_17.Value;
                S18 = app.Slider_18.Value;
                S19 = app.Slider_19.Value;
                S20 = app.Slider_20.Value;
                S21 = app.Slider_21.Value;
                S22 = app.Slider_22.Value;
                S23 = app.Slider_23.Value;
                S24 = app.Slider_24.Value;
                S25 = app.Slider_25.Value;
                S26 = app.Slider_26.Value;
                S27 = app.Slider_27.Value;
                S28 = app.Slider_28.Value;
                S29 = app.Slider_29.Value;
                S30 = app.Slider_30.Value;
                S31 = app.Slider_31.Value;
                
                Mrplus = Mrp(app,Fs);
                b = num(app,target(app,S1,S2,S3,S4,S5,S6,S7,...
                S8,S9,S10,S11,S12,S13,S14,S15,S16,S17,S18,...
                S19,S20,S21,S22,S23,S24,S25,S26,S27,S28,...
                S29,S30,S31),Mrplus);
                
                dF = Fs/1024;
                f = -Fs/2:dF:Fs/2-dF;
                g = [S1,S2,S3,S4,S5,S6,S7,...
                S8,S9,S10,S11,S12,S13,S14,S15,S16,S17,S18,...
                S19,S20,S21,S22,S23,S24,S25,S26,S27,S28,...
                S29,S30,S31];
                save 'b.mat' b;
                save 'g.mat' g;
                
                
                i=0;
                while ~isDone(fileReader)

                    %Acquiring the succeeding frame
                    xk = fileReader();
                    
                    if length(xk(1,:))~=2
                        xk = [xk,xk];
                        xk1 = xk(:,1)';
                        xk2 = xk(:,2)';
                    else
                        xk1 = xk(:,1)';
                        xk2 = xk(:,2)';
                     end
                        if strcmp(app.PlayButton.Text,'Play') == 1
                            %Pause loop
                            while strcmp(app.PlayButton.Text,'Play') == 1 && ...
                                app.isStop == 0
                                pause(1);
                            end
                        end
                        pause(0);
                        
                        %Checking if slider configuration changed
                        if app.Slider_1.Value ~= S1 || app.Slider_2.Value ~= S2 ...
                            || ...
                            app.Slider_3.Value ~= S3 || ...
                            S4 ~= app.Slider_4.Value || ...
                            S5 ~= app.Slider_5.Value || ...
                            app.Slider_6.Value ~= S6 || ...
                            app.Slider_7.Value ~= S7 || ...
                            app.Slider_8.Value ~= S8 || ...
                            S9 ~= app.Slider_9.Value || ...
                            S10 ~= app.Slider_10.Value || ...
                            app.Slider_11.Value ~= S11 || ...
                            app.Slider_12.Value ~= S12 || ...
                            app.Slider_13.Value ~= S13 || ...
                            S14 ~= app.Slider_14.Value || ...
                            S15 ~= app.Slider_15.Value || ...
                            app.Slider_16.Value ~= S16 || ...
                            app.Slider_17.Value ~= S17 || ...
                            app.Slider_18.Value ~= S18 || ...
                            S19 ~= app.Slider_19.Value || ...
                            S20 ~= app.Slider_20.Value || ...
                            app.Slider_21.Value ~= S21 || ...
                            app.Slider_22.Value ~= S22 || ...
                            app.Slider_23.Value ~= S23 || ...
                            S24 ~= app.Slider_24.Value || ...
                            S25 ~= app.Slider_25.Value || ...
                            app.Slider_26.Value ~= S26 || ...
                            app.Slider_27.Value ~= S27 || ...
                            app.Slider_28.Value ~= S28 || ...
                            S29 ~= app.Slider_29.Value || ...
                            S30 ~= app.Slider_30.Value || ...
                            app.Slider_31.Value ~= S31
                            
                            S1 = app.Slider_1.Value;
                            S2 = app.Slider_2.Value;
                            S3 = app.Slider_3.Value;
                            S4 = app.Slider_4.Value;
                            S5 = app.Slider_5.Value;
                            S6 = app.Slider_6.Value;
                            S7 = app.Slider_7.Value;
                            S8 = app.Slider_8.Value;
                            S9 = app.Slider_9.Value;
                            S10 = app.Slider_10.Value;
                            S11 = app.Slider_11.Value;
                            S12 = app.Slider_12.Value;
                            S13 = app.Slider_13.Value;
                            S14 = app.Slider_14.Value;
                            S15 = app.Slider_15.Value;
                            S16 = app.Slider_16.Value;
                            S17 = app.Slider_17.Value;
                            S18 = app.Slider_18.Value;
                            S19 = app.Slider_19.Value;
                            S20 = app.Slider_20.Value;
                            S21 = app.Slider_21.Value;
                            S22 = app.Slider_22.Value;
                            S23 = app.Slider_23.Value;
                            S24 = app.Slider_24.Value;
                            S25 = app.Slider_25.Value;
                            S26 = app.Slider_26.Value;
                            S27 = app.Slider_27.Value;
                            S28 = app.Slider_28.Value;
                            S29 = app.Slider_29.Value;
                            S30 = app.Slider_30.Value;
                            S31 = app.Slider_31.Value;
                            
                            %Calculating the new filters
                            Mrplus = Mrp(app,Fs);
                            b = num(app,target(app,S1,S2,S3,S4,S5,S6,S7,S8,S9, ...
                            S10,S11,S12,S13,S14,S15,S16,S17,S18,S19,S20,...
                            S21,S22, ...
                            S23,S24,S25,S26,S27,S28,S29,S30,S31),...
                            Mrplus);
                        end   
                        
                    %Filtering process
                    for n=1:63
                    if n<63
                        ykNew1(n,:) = filterNew(app,b((2*n-1):(2*n)),...
                        xk1,ybuffer1(n,:),xbuffer1,Fs,n);
                        ykNew2(n,:) = filterNew(app,b((2*n-1):(2*n)),...
                        xk2,ybuffer2(n,:),xbuffer2,Fs,n);
                    else
                        ykNew1(n,:) = xk1*b(125);
                        ykNew2(n,:) = xk2*b(125);
                    end
                    
                    end
                    
                    
                    yk1=0;
                    yk2=0;
                    for n=1:63
                    yk1=yk1 + ykNew1(n,:);
                    
                    yk2=yk2 + ykNew2(n,:);
                    end
                    
                    %Playback of frame
                    deviceWriter([0.25*yk1',0.25*yk2']);
                    
                    %Delay updates
                    xbuffer1 = flip(xk1(length(xk1)-1:length(xk1)));
                    xbuffer2 = flip(xk2(length(xk2)-1:length(xk2)));
                    for n=1:62
                    
                        ybuffer1(n,:)=flip(ykNew1(n,...
                        (length(ykNew1(n,:))-2):(length(ykNew1(n,:)))));
                    
                        ybuffer2(n,:)=flip(ykNew2(n,...
                        (length(ykNew2(n,:))-2):(length(ykNew2(n,:)))));
                    
                    end                    
                                       
                    %FFT plot update if allowed
                    if mod(i,51-round(app.Knob.Value))==0 && ...
                            strcmp(app.Switch.Value,'On') == 1
                        z = fftshift(fft(yk1));
                        area(app.UIAxes,f,abs(z)/1024)
                        app.UIAxes.XLim = [0 20000];
                        app.UIAxes.YLim = [0 0.02];
                        drawnow limitrate;
                    end


                    %deviceWriter([0.25*xk1',0.25*xk2']);
                    
                    %update slider and time
                        if mod(i,20)==0 && ~app.isforwardback
                            app.audioSlider.Value = i;
                        end
                        %app.Label = tostring(i);
                        %app.Label.Text =
                        %string(fileReader.SamplesPerFrame/fileReader.SampleRate);
                        time_now = i/app.total_i*app.duration;
                        time_now.Format = 'mm:ss';
                        app.Label.Text = string(time_now);
                        %duration.Format = 'mm:ss';
                        
                        i=i+1;
                    % check forward_back
                    if app.isforwardback 
                        
                        fileReader.release;%realease 
                        %read new with new range 
                        fileReader = dsp.AudioFileReader(...
                        fileName,...
                        'SamplesPerFrame',1024); 
                        i = floor(app.audioSlider.Value);
                        fileReader.ReadRange=[floor(app.audioSlider.Value)*1024 inf];
                        
                        app.isforwardback = 0;
                        
                    end
                    
                    %% press Stop while play
                    if app.isStop == 1
                        release(fileReader);
                        release(deviceWriter);
                        app.PlayButton.Text = 'Play';
                        app.isPlay = 0;
                        app.isStop = 0;
                    end
                end
                    
                app.audioSlider.Value = app.audioSlider.Limits(1);
                app.Label.Text = '0:00';
                release(fileReader);
                release(deviceWriter);
                app.PlayButton.Text = 'Play';
                app.isPlay = 0;    
            elseif strcmp(app.PlayButton.Text,'Play') && ...
                app.isPlay == 1
                app.PlayButton.Text = 'Pause';
            elseif app.isStop == 1
                release(fileReader);
                release(deviceWriter);
                app.PlayButton.Text = 'Play';
                app.isPlay = 0;
                app.isStop = 0;
            
            else
                app.PlayButton.Text = 'Play';
            end
                
        end

        % Value changed function: Slider_1
        function Slider_1ValueChanged(app, event)
            value = app.Slider_1.Value;
            app.Duration.Text = num2str(value);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStop = 1;
        end

        % Value changed function: audioSlider
        function audioSliderValueChanged(app, event)
            app.isforwardback = 1;
            
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
           
            value = app.ModeDropDown.Value;
            if strcmp('Rock',value) == 1
                app.Slider_1.Value = 7;
                app.Slider_2.Value = 7;app.Slider_3.Value = 6;
                app.Slider_4.Value = 6;app.Slider_5.Value = 5;
                app.Slider_6.Value = 5;app.Slider_7.Value = 5;
                app.Slider_8.Value = 3;app.Slider_9.Value = 2;
                app.Slider_10.Value = 1;app.Slider_11.Value = 0;
                app.Slider_12.Value = 0;app.Slider_13.Value = -1;
                app.Slider_14.Value = -1;app.Slider_15.Value = -2;
                app.Slider_16.Value = -3;app.Slider_17.Value = -3;
                app.Slider_18.Value = -4;app.Slider_19.Value = -3;
                app.Slider_20.Value = -3;app.Slider_21.Value = -2;
                app.Slider_22.Value = -1;app.Slider_23.Value = 0;
                app.Slider_24.Value = 2;app.Slider_25.Value = 3;
                app.Slider_26.Value = 4;app.Slider_27.Value = 5;
                app.Slider_28.Value = 5;app.Slider_29.Value = 5;
                app.Slider_30.Value = 5;app.Slider_31.Value = 4;
            
            elseif strcmp('Flat',value) == 1
                app.Slider_1.Value = 0;
                app.Slider_2.Value = 0;app.Slider_3.Value = 0;
                app.Slider_4.Value = 0;app.Slider_5.Value = 0;
                app.Slider_6.Value = 0;app.Slider_7.Value = 0;
                app.Slider_8.Value = 0;app.Slider_9.Value = 0;
                app.Slider_10.Value = 0;app.Slider_11.Value = 0;
                app.Slider_12.Value = 0;app.Slider_13.Value = 0;
                app.Slider_14.Value = 0;app.Slider_15.Value = 0;
                app.Slider_16.Value = 0;app.Slider_17.Value = 0;
                app.Slider_18.Value = 0;app.Slider_19.Value = 0;
                app.Slider_20.Value = 0;app.Slider_21.Value = 0;
                app.Slider_22.Value = 0;app.Slider_23.Value = 0;
                app.Slider_24.Value = 0;app.Slider_25.Value = 0;
                app.Slider_26.Value = 0;app.Slider_27.Value = 0;
                app.Slider_28.Value = 0;app.Slider_29.Value = 0;
                app.Slider_30.Value = 0;app.Slider_31.Value = 0;
            
            elseif strcmp('Pop',value) == 1
                app.Slider_1.Value = 0;
                app.Slider_2.Value = 0;app.Slider_3.Value = 0;
                app.Slider_4.Value = 0;app.Slider_5.Value = 1;
                app.Slider_6.Value = 1;app.Slider_7.Value = 2;
                app.Slider_8.Value = 3;app.Slider_9.Value = 4;
                app.Slider_10.Value = 4;app.Slider_11.Value = 4;
                app.Slider_12.Value = 3;app.Slider_13.Value = 2;
                app.Slider_14.Value = 1;app.Slider_15.Value = 0;
                app.Slider_16.Value = 0;app.Slider_17.Value = 0;
                app.Slider_18.Value = -1;app.Slider_19.Value = -1;
                app.Slider_20.Value = -2;app.Slider_21.Value = -2;
                app.Slider_22.Value = -2;app.Slider_23.Value = -2;
                app.Slider_24.Value = -1;app.Slider_25.Value = -1;
                app.Slider_26.Value = 0;app.Slider_27.Value = 1;
                app.Slider_28.Value = 0;app.Slider_29.Value = -1;
                app.Slider_30.Value = -1;app.Slider_31.Value = -1;
            
            elseif strcmp('Bass',value) == 1
                app.Slider_1.Value = 12;
                app.Slider_2.Value = 12;app.Slider_3.Value = 12;
                app.Slider_4.Value = 12;app.Slider_5.Value = 12;
                app.Slider_6.Value = 12;app.Slider_7.Value = 12;
                app.Slider_8.Value = 12;app.Slider_9.Value = 11;
                app.Slider_10.Value = 10;app.Slider_11.Value = 9;
                app.Slider_12.Value = 8;app.Slider_13.Value = 7;
                app.Slider_14.Value = 6;app.Slider_15.Value = 5;
                app.Slider_16.Value = 4;app.Slider_17.Value = 3;
                app.Slider_18.Value = 2;app.Slider_19.Value = 1;
                app.Slider_20.Value = 0;app.Slider_21.Value = -1;
                app.Slider_22.Value = -2;app.Slider_23.Value = -3;
                app.Slider_24.Value = -4;app.Slider_25.Value = -5;
                app.Slider_26.Value = -6;app.Slider_27.Value = -7;
                app.Slider_28.Value = -8;app.Slider_29.Value = -9;
                app.Slider_30.Value = -10;app.Slider_31.Value = ...
                -11;
            
            elseif strcmp('Treble',value) == 1
                app.Slider_1.Value = -12;
                app.Slider_2.Value = -11;app.Slider_3.Value = -10;
                app.Slider_4.Value = -10;app.Slider_5.Value = -10;
                app.Slider_6.Value = -10;app.Slider_7.Value = -9;
                app.Slider_8.Value = -8;app.Slider_9.Value = -7;
                app.Slider_10.Value = -7;app.Slider_11.Value = -7;
                app.Slider_12.Value = -6;app.Slider_13.Value = -6;
                app.Slider_14.Value = -5;app.Slider_15.Value = -5;
                app.Slider_16.Value = -3;app.Slider_17.Value = -2;
                app.Slider_18.Value = 0;app.Slider_19.Value = 3;
                app.Slider_20.Value = 5;app.Slider_21.Value = 6;
                app.Slider_22.Value = 8;app.Slider_23.Value = 9;
                app.Slider_24.Value = 9;app.Slider_25.Value = 10;
                app.Slider_26.Value = 11;app.Slider_27.Value = 12;
                app.Slider_28.Value = 12;app.Slider_29.Value = 12;
                app.Slider_30.Value = 12;app.Slider_31.Value = 12;
            
            elseif strcmp('Vocal',value) == 1
                app.Slider_1.Value = -8;
                app.Slider_2.Value = -7;app.Slider_3.Value = -6;
                app.Slider_4.Value = -5;app.Slider_5.Value = -5;
                app.Slider_6.Value = -4;app.Slider_7.Value = -3;
                app.Slider_8.Value = -3;app.Slider_9.Value = 0;
                app.Slider_10.Value = 0;app.Slider_11.Value = 2;
                app.Slider_12.Value = 5;app.Slider_13.Value = 6;
                app.Slider_14.Value = 7;app.Slider_15.Value = 7;
                app.Slider_16.Value = 7;app.Slider_17.Value = 7;
                app.Slider_18.Value = 7;app.Slider_19.Value = 7;
                app.Slider_20.Value = 6;app.Slider_21.Value = 4;
                app.Slider_22.Value = 3;app.Slider_23.Value = 2;
                app.Slider_24.Value = 0;app.Slider_25.Value = -2;
                app.Slider_26.Value = -5;app.Slider_27.Value = -5;
                app.Slider_28.Value = -5;app.Slider_29.Value = -5;
                app.Slider_30.Value = -5;app.Slider_31.Value = -7;
                
            elseif strcmp('Classical',value) == 1
                app.Slider_1.Value = -3;
                app.Slider_2.Value = -2;app.Slider_3.Value = -1;
                app.Slider_4.Value = 0;app.Slider_5.Value = 1;
                app.Slider_6.Value = 1;app.Slider_7.Value = 1;
                app.Slider_8.Value = 2;app.Slider_9.Value = 2;
                app.Slider_10.Value = 3;app.Slider_11.Value = 3;
                app.Slider_12.Value = 2;app.Slider_13.Value = 1;
                app.Slider_14.Value = 0;app.Slider_15.Value = -1;
                app.Slider_16.Value = -2;app.Slider_17.Value = -4;
                app.Slider_18.Value = -6;app.Slider_19.Value = -8;
                app.Slider_20.Value = -8;app.Slider_21.Value = -5;
                app.Slider_22.Value = -3;app.Slider_23.Value = -1;
                app.Slider_24.Value = 0;app.Slider_25.Value = 1;
                app.Slider_26.Value = 2;app.Slider_27.Value = 4;
                app.Slider_28.Value = 3;app.Slider_29.Value = 1;
                app.Slider_30.Value = 0;app.Slider_31.Value = -1;
            
            elseif strcmp('Hip-Hop',value) == 1
                app.Slider_1.Value = 4;
                app.Slider_2.Value = 4;app.Slider_3.Value = 4;
                app.Slider_4.Value = 4;app.Slider_5.Value = 3;
                app.Slider_6.Value = 3;app.Slider_7.Value = 2;
                app.Slider_8.Value = 1;app.Slider_9.Value = 1;
                app.Slider_10.Value = 1;app.Slider_11.Value = 0;
                app.Slider_12.Value = 0;app.Slider_13.Value = -1;
                app.Slider_14.Value = -1;app.Slider_15.Value = -2;
                app.Slider_16.Value = -2;app.Slider_17.Value = -3;
                app.Slider_18.Value = -3;app.Slider_19.Value = -2;
                app.Slider_20.Value = -2;app.Slider_21.Value = -1;
                app.Slider_22.Value = 0;app.Slider_23.Value = 1;
                app.Slider_24.Value = 1;app.Slider_25.Value = 2;
                app.Slider_26.Value = 3;app.Slider_27.Value = 3;
                app.Slider_28.Value = 3;app.Slider_29.Value = 4;
                app.Slider_30.Value = 3;app.Slider_31.Value = 2;
            
            elseif strcmp('Dance',value) == 1
                app.Slider_1.Value = 6;
                app.Slider_2.Value = 6;app.Slider_3.Value = 6;
                app.Slider_4.Value = 6;app.Slider_5.Value = 6;
                app.Slider_6.Value = 7;app.Slider_7.Value = 6;
                app.Slider_8.Value = 4;app.Slider_9.Value = 2;
                app.Slider_10.Value = 1;app.Slider_11.Value = 1;
                app.Slider_12.Value = 0;app.Slider_13.Value = -1;
                app.Slider_14.Value = -2;app.Slider_15.Value = -2;
                app.Slider_16.Value = -1;app.Slider_17.Value = -1;
                app.Slider_18.Value = 0;app.Slider_19.Value = 1;
                app.Slider_20.Value = 1;app.Slider_21.Value = 2;
                app.Slider_22.Value = 3;app.Slider_23.Value = 2;
                app.Slider_24.Value = 2;app.Slider_25.Value = 1;
                app.Slider_26.Value = 0;app.Slider_27.Value = -1;
                app.Slider_28.Value = -1;app.Slider_29.Value = -2;
                app.Slider_30.Value = -2;app.Slider_31.Value = -2;
            
            elseif strcmp('Jazz',value) == 1
                app.Slider_1.Value = -3;
                app.Slider_2.Value = -2;app.Slider_3.Value = -2;
                app.Slider_4.Value = 0;app.Slider_5.Value = 2;
                app.Slider_6.Value = 3;app.Slider_7.Value = 1;
                app.Slider_8.Value = -2;app.Slider_9.Value = -6;
                app.Slider_10.Value = -3;app.Slider_11.Value = -1;
                app.Slider_12.Value = 0;app.Slider_13.Value = 1;
                app.Slider_14.Value = 3;app.Slider_15.Value = 6;
                app.Slider_16.Value = 5;app.Slider_17.Value = 4;
                app.Slider_18.Value = 3;app.Slider_19.Value = 2;
                app.Slider_20.Value = 2;app.Slider_21.Value = 1;
                app.Slider_22.Value = 1;app.Slider_23.Value = 0;
                app.Slider_24.Value = 0;app.Slider_25.Value = 0;
                app.Slider_26.Value = 0;app.Slider_27.Value = 0;
                app.Slider_28.Value = 0;app.Slider_29.Value = -1;
                app.Slider_30.Value = -1;app.Slider_31.Value = -1;
            end
      
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FFT')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Power (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Image11 = uiimage(app.UIFigure);
            app.Image11.ImageSource = 'brown-wooden-flooring (1).png';
            app.Image5_3 = uiimage(app.UIFigure);
            app.Image5_3.ImageSource = 'pngegg.png';
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image5_2.ImageSource = 'pngegg.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'pngegg.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'peakpx.jpg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '20';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Slider_1 = uislider(app.UIFigure);
            app.Slider_1.Limits = [-12 12];
            app.Slider_1.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_1.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_1.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Duration = uilabel(app.UIFigure);
            app.Duration.Text = 'Time';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '25';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-12 12];
            app.Slider_2.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_2.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_2.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '31.5';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-12 12];
            app.Slider_3.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_3.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_3.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '63';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-12 12];
            app.Slider_4.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_4.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_4.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '40';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-12 12];
            app.Slider_5.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_5.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_5.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '50';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [-12 12];
            app.Slider_6.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_6.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_6.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '80';
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_7.Limits = [-12 12];
            app.Slider_7.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_7.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_7.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '100';
            app.Slider_8 = uislider(app.UIFigure);
            app.Slider_8.Limits = [-12 12];
            app.Slider_8.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_8.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_8.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '120';
            app.Slider_9 = uislider(app.UIFigure);
            app.Slider_9.Limits = [-12 12];
            app.Slider_9.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_9.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_9.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '160';
            app.Slider_10 = uislider(app.UIFigure);
            app.Slider_10.Limits = [-12 12];
            app.Slider_10.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_10.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_10.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '200';
            app.Slider_11 = uislider(app.UIFigure);
            app.Slider_11.Limits = [-12 12];
            app.Slider_11.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_11.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_11.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '250';
            app.Slider_12 = uislider(app.UIFigure);
            app.Slider_12.Limits = [-12 12];
            app.Slider_12.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_12.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_12.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '315';
            app.Slider_13 = uislider(app.UIFigure);
            app.Slider_13.Limits = [-12 12];
            app.Slider_13.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_13.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_13.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '400';
            app.Slider_14 = uislider(app.UIFigure);
            app.Slider_14.Limits = [-12 12];
            app.Slider_14.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_14.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_14.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '500';
            app.Slider_15 = uislider(app.UIFigure);
            app.Slider_15.Limits = [-12 12];
            app.Slider_15.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_15.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_15.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '630';
            app.Slider_16 = uislider(app.UIFigure);
            app.Slider_16.Limits = [-12 12];
            app.Slider_16.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_16.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_16.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '800';
            app.Slider_17 = uislider(app.UIFigure);
            app.Slider_17.Limits = [-12 12];
            app.Slider_17.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_17.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_17.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = '1K';
            app.Slider_18 = uislider(app.UIFigure);
            app.Slider_18.Limits = [-12 12];
            app.Slider_18.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_18.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_18.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_2Label = uilabel(app.UIFigure);
            app.KSlider_2Label.Text = '2K';
            app.Slider_19 = uislider(app.UIFigure);
            app.Slider_19.Limits = [-12 12];
            app.Slider_19.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_19.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_19.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_3Label = uilabel(app.UIFigure);
            app.KSlider_3Label.Text = '2.5K';
            app.Slider_20 = uislider(app.UIFigure);
            app.Slider_20.Limits = [-12 12];
            app.Slider_20.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_20.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_20.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_4Label = uilabel(app.UIFigure);
            app.KSlider_4Label.Text = '3.15K';
            app.Slider_21 = uislider(app.UIFigure);
            app.Slider_21.Limits = [-12 12];
            app.Slider_21.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_21.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_21.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_5Label = uilabel(app.UIFigure);
            app.KSlider_5Label.Text = '4K';
            app.Slider_22 = uislider(app.UIFigure);
            app.Slider_22.Limits = [-12 12];
            app.Slider_22.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_22.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_22.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_6Label = uilabel(app.UIFigure);
            app.KSlider_6Label.Text = '5K';
            app.Slider_23 = uislider(app.UIFigure);
            app.Slider_23.Limits = [-12 12];
            app.Slider_23.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_23.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_23.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_7Label = uilabel(app.UIFigure);
            app.KSlider_7Label.Text = '6.3K';
            app.Slider_24 = uislider(app.UIFigure);
            app.Slider_24.Limits = [-12 12];
            app.Slider_24.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_24.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_24.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_8Label = uilabel(app.UIFigure);
            app.KSlider_8Label.Text = '8K';
            app.Slider_25 = uislider(app.UIFigure);
            app.Slider_25.Limits = [-12 12];
            app.Slider_25.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_25.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_25.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_9Label = uilabel(app.UIFigure);
            app.KSlider_9Label.Text = '10K';
            app.Slider_26 = uislider(app.UIFigure);
            app.Slider_26.Limits = [-12 12];
            app.Slider_26.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_26.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_26.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_10Label = uilabel(app.UIFigure);
            app.KSlider_10Label.Text = '12.5K';
            app.Slider_27 = uislider(app.UIFigure);
            app.Slider_27.Limits = [-12 12];
            app.Slider_27.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_27.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_27.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_11Label = uilabel(app.UIFigure);
            app.KSlider_11Label.Text = '16K';
            app.Slider_28 = uislider(app.UIFigure);
            app.Slider_28.Limits = [-12 12];
            app.Slider_28.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_28.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_28.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_12Label = uilabel(app.UIFigure);
            app.KSlider_12Label.Text = '20K';
            app.Slider_29 = uislider(app.UIFigure);
            app.Slider_29.Limits = [-12 12];
            app.Slider_29.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_29.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_29.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_13Label = uilabel(app.UIFigure);
            app.KSlider_13Label.Text = '25K';
            app.Slider_30 = uislider(app.UIFigure);
            app.Slider_30.Limits = [-12 12];
            app.Slider_30.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_30.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_30.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.KSlider_14Label = uilabel(app.UIFigure);
            app.KSlider_14Label.Text = '31.5K';
            app.Slider_31 = uislider(app.UIFigure);
            app.Slider_31.Limits = [-12 12];
            app.Slider_31.MajorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider_31.MajorTickLabels = {'-12', '', '', '-9', '', '', '-6', '', '', '-3', '', '', '0', '', '', '3', '', '', '6', '', '', '9', '', '', '12'};
            app.Slider_31.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.songLabel = uilabel(app.UIFigure);
            app.songLabel.Text = 'song';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TrackDropDown = uidropdown(app.UIFigure);
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Custom', 'Rock', 'Flat', 'Pop', 'Bass', 'Treble', 'Vocal', 'Classical', 'Hip-hop', 'Dance', 'Jazz'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Custom';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 50];
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.audioSlider = uislider(app.UIFigure);
            app.audioSlider.Limits = [0 12000];
            app.audioSlider.MajorTicks = [];
            app.audioSlider.MajorTickLabels = {''};
            app.audioSlider.ValueChangedFcn = createCallbackFcn(app, @audioSliderValueChanged, true);
            app.audioSlider.MinorTicks = [];
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'control.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Time';
            app.Image7 = uiimage(app.UIFigure);
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = 'screw.png';
            app.Image8_2 = uiimage(app.UIFigure);
            app.Image8_2.ImageSource = 'screw.png';
            app.Image8_3 = uiimage(app.UIFigure);
            app.Image8_3.ImageSource = 'screw.png';
            app.Image8_4 = uiimage(app.UIFigure);
            app.Image8_4.ImageSource = 'screw.png';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Image10 = uiimage(app.UIFigure);
            app.Image10.ImageSource = 'headphones.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.ModeDropDown.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end